classdef batteryAppValidation < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        Button4         matlab.ui.control.Button
        Label5          matlab.ui.control.Label
        T1              matlab.ui.control.NumericEditField
        I_field_1       matlab.ui.control.NumericEditField
        Label2          matlab.ui.control.Label
        Button3         matlab.ui.control.Button
        exp1            matlab.ui.control.EditField
        Label           matlab.ui.control.Label
        filename        matlab.ui.control.EditField
        LabelEditField  matlab.ui.control.Label
        Slider2         matlab.ui.control.Slider
        Slider          matlab.ui.control.Slider
        UIAxes          matlab.ui.control.UIAxes
        Label9          matlab.ui.control.Label
        T2              matlab.ui.control.NumericEditField
        exp2            matlab.ui.control.EditField
        Label11         matlab.ui.control.Label
        Label12         matlab.ui.control.Label
        T3              matlab.ui.control.NumericEditField
        exp3            matlab.ui.control.EditField
        Label14         matlab.ui.control.Label
        Label15         matlab.ui.control.Label
        T4              matlab.ui.control.NumericEditField
        Label16         matlab.ui.control.Label
        I_field_4       matlab.ui.control.NumericEditField
        exp4            matlab.ui.control.EditField
        Label17         matlab.ui.control.Label
        Label18         matlab.ui.control.Label
        I_field_2       matlab.ui.control.NumericEditField
        Label19         matlab.ui.control.Label
        I_field_3       matlab.ui.control.NumericEditField
        enterDataBtn    matlab.ui.control.Button
        UIAxes2         matlab.ui.control.UIAxes
        Label20         matlab.ui.control.Label
        Label21         matlab.ui.control.Label
        Label25         matlab.ui.control.Label
        T7              matlab.ui.control.NumericEditField
        Label26         matlab.ui.control.Label
        T6              matlab.ui.control.NumericEditField
        Label27         matlab.ui.control.Label
        I_field_7       matlab.ui.control.NumericEditField
        Label28         matlab.ui.control.Label
        I_field_6       matlab.ui.control.NumericEditField
        exp7            matlab.ui.control.EditField
        Label29         matlab.ui.control.Label
        exp6            matlab.ui.control.EditField
        Label30         matlab.ui.control.Label
        Label31         matlab.ui.control.Label
        T5              matlab.ui.control.NumericEditField
        Label32         matlab.ui.control.Label
        I_field_5       matlab.ui.control.NumericEditField
        exp5            matlab.ui.control.EditField
        Label33         matlab.ui.control.Label
        Label34         matlab.ui.control.Label
        Label35         matlab.ui.control.Label
        Label36         matlab.ui.control.Label
    end

    
    properties (Access = public)
        V % Description
        R0 % Description
        SOC % Description
        SOC_exp % Description
        V_exp % Description
        expData % Description
        I % Description
        T % Description
        Ea % Description
        exper % Description
        Em % OCV
        R % internal resistance f(T)
        Current
        Temperature
    end
    
    methods (Access = public)
        
        function Ival(app)
            app.I = [app.I_field_1.Value app.I_field_2.Value app.I_field_3.Value app.I_field_4.Value];
        end
        
        function Tval(app)
            app.T = [app.T1.Value app.T2.Value app.T3.Value app.T4.Value];
        end
        
        function out = RofT(app,Temp)
            out = app.R0 .* exp(app.Ea/8.314*(1/(273+Temp)-1/(273+app.T(1))));
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.filename.Value = 'sanyo.mat';
            Cur = {'1p5A' '10A' '10A' '10A' '10A' '15A' '20A'};
            CurN = [1.5 10 10 10 10 15 20];
            Tmp = {'25C' '25C' '0C' '60C' 'm10C' '25C' '25C'};
            TmpN = [25 25 0 60 -10 25 25];
            for p=1:7
                app.(['exp' num2str(p)]).Value = ['sanyo_' Cur{p} '_' Tmp{p}];
                app.(['I_field_' num2str(p)]).Value = CurN(p);
                app.(['T' num2str(p)]).Value = TmpN(p);
                app.exper{p} = app.(['exp' num2str(p)]).Value;
            end
            app.Temperature = 25;
            app.Current = 1;
        end

        % Value changed function: I_field_1
        function I_cbk_1(app, event)
            app.I(1) = app.I_field_1.Value;
        end

        % Value changed function: I_field_2
        function I_cbk_2(app, event)
            app.I(2) = app.I_field_2.Value;
        end

        % Value changed function: I_field_3
        function I_cbk_3(app, event)
            app.I(3) = app.I_field_3.Value;
        end

        % Value changed function: I_field_4
        function I_cbk_4(app, event)
            app.I(4) = app.I_field_4.Value;
        end

        % Value changed function: I_field_5
        function I_cbk_5(app, event)
            app.I(5) = app.I_field_5.Value;
        end

        % Value changed function: I_field_6
        function I_cbk_6(app, event)
            app.I(6) = app.I_field_6.Value;
        end

        % Value changed function: I_field_7
        function I_cbk_7(app, event)
            app.I(7) = app.I_field_7.Value;
        end

        % Callback function
        function R0_cbk(app, event)
            value = app.R0;
        end

        % Value changing function: Slider
        function SliderI(app, event)
                app.Current = event.Value;
                app.V = app.Em - app.Current * RofT(app,app.Temperature);
                p = plot(app.UIAxes,flipud(app.SOC_exp),[app.V_exp(:,1) app.V_exp(:,2) app.V_exp(:,3) app.V_exp(:,4) ...
                    app.V_exp(:,5) app.V_exp(:,6) app.V_exp(:,7) app.V]);
                p(1).Color = 'k'; p(2).Color = 'b'; p(3).Color = 'r'; p(4).Color = 'g';
                for i=5:7
                    p(i).Marker = 'x';
                end
                p(5).Color = 'k'; p(6).Color = 'b'; p(7).Color = 'r';
                p(8).Marker = 'o'; p(8).MarkerFaceColor = 'k';  p(8).MarkerSize = 2;
        end

        % Value changing function: Slider2
        function SliderT(app, event)
                app.Temperature = event.Value;
                app.R = RofT(app,app.Temperature);
                app.V = app.Em - app.Current * app.R;
                plot(app.UIAxes2,flipud(app.SOC_exp),app.R0,flipud(app.SOC_exp),app.R)
                p = plot(app.UIAxes,flipud(app.SOC_exp),[app.V_exp(:,1) app.V_exp(:,2) app.V_exp(:,3) app.V_exp(:,4) ...
                    app.V_exp(:,5) app.V_exp(:,6) app.V_exp(:,7) app.V]);
                p(1).Color = 'k'; p(2).Color = 'b'; p(3).Color = 'r'; p(4).Color = 'g';
                for i=5:7
                    p(i).Marker = 'x';
                end
                p(5).Color = 'k'; p(6).Color = 'b'; p(7).Color = 'r';
                p(8).Marker = 'o'; p(8).MarkerFaceColor = 'k';  p(8).MarkerSize = 2;
        end

        % Value changed function: T1
        function T_cbk_1(app, event)
            app.T(1) = app.T1.Value;
        end

        % Value changed function: T2
        function T_cbk_2(app, event)
            app.T(2) = app.T2.Value;
        end

        % Value changed function: T3
        function T_cbk_3(app, event)
            app.T(3) = app.T3.Value;
        end

        % Value changed function: T4
        function T_cbk_4(app, event)
            app.T(4) = app.T4.Value;
        end

        % Value changed function: T5
        function T_cbk_5(app, event)
            app.T(5) = app.T5.Value;
        end

        % Value changed function: T6
        function T_cbk_6(app, event)
            app.T(6) = app.T6.Value;
        end

        % Value changed function: T7
        function T_cbk_7(app, event)
            app.T(7) = app.T7.Value;
        end

        % Button pushed function: Button4
        function calculateParam(app, event)
            app.R0 = abs((app.V_exp(:,2)-app.V_exp(:,1))/(app.I(2)-app.I(1)));
            app.Em = app.V_exp(:,1) + app.I(1) * app.R0;
            app.Ea = abs(8.314 * log((app.Em-app.V_exp(:,4))./(app.Em-app.V_exp(:,3)))/(1/(273+app.T(4)) - 1/(273+app.T(3))));
            plot(app.UIAxes2,flipud(app.SOC_exp),app.R0)
        end

        % Button pushed function: enterDataBtn
        function enterDataBtnCbk(app, event)
            for i=1:7
                app.exper{i} = app.(['exp' num2str(i)]).Value;
                app.I(i) = app.(['I_field_' num2str(i)]).Value;
                app.T(i) = app.(['T' num2str(i)]).Value;
            end
            if isempty(app.filename.Value)
                app.filename.Value = 'sanyo.mat';
            end
            if isempty(app.exp1.Value)
                app.exp1.Value = 'sanyo_1p5A_25C';
            end
            if isempty(app.exp2.Value)
                app.exp2.Value = 'sanyo_10A_25C';
            end
            if isempty(app.exp3.Value)
                app.exp3.Value = 'sanyo_10A_0C';
            end
            if isempty(app.exp4.Value)
                app.exp4.Value = 'sanyo_10A_60C';
            end
            if isempty(app.exp5.Value)
                app.exp5.Value = 'sanyo_10A_m10C';
            end
            if isempty(app.exp6.Value)
                app.exp6.Value = 'sanyo_15A_25C';
            end
            if isempty(app.exp7.Value)
                app.exp7.Value = 'sanyo_20A_25C';
            end
            app.SOC_exp = 0:.01:1;
            Ival(app);
            Tval(app);
            app.expData = load(app.filename.Value);
            for k=1:7
            %                 app.expData = load(app.filename.Value,app.exper{k});
                temp = app.expData.(app.exper{k});
                idx1 = isnan(temp(:,1));
            %                 idx2 = isnan(temp(:,2));
                temp = temp(~idx1,:);
                [rowz,~,~]=find(temp(:,1));
                temp = temp(1:max(rowz),:);
                % x-axis normalization
                x = temp(:,1)/temp(max(rowz),1);
                y = temp(:,2);
                % y-axis definition
                fitV = fit(x,y,'smoothingspline');
                app.V_exp(:,k) = fitV(app.SOC_exp);
            end
            p = plot(app.UIAxes,flipud(app.SOC_exp),[app.V_exp(:,1) app.V_exp(:,2) app.V_exp(:,3) app.V_exp(:,4) ...
                app.V_exp(:,5) app.V_exp(:,6) app.V_exp(:,7)]);
            p(1).Color = 'k'; p(2).Color = 'b'; p(3).Color = 'r'; p(4).Color = 'g'; 
            for i=5:7
                p(i).Marker = 'x';
            end
            p(5).Color = 'k'; p(6).Color = 'b'; p(7).Color = 'r';
        end

        % Value changed function: exp1
        function exp1ValueChanged(app, event)
            app.exper{1} = app.exp1.Value;
        end

        % Value changed function: exp2
        function exp2ValueChanged(app, event)
            app.exper{2} = app.exp2.Value;
        end

        % Value changed function: exp3
        function exp3ValueChanged(app, event)
            app.exper{3} = app.exp3.Value;
        end

        % Value changed function: exp4
        function exp4ValueChanged(app, event)
            app.exper{4} = app.exp4.Value;
        end

        % Value changed function: exp5
        function exp5ValueChanged(app, event)
            app.exper{5} = app.exp5.Value;
        end

        % Value changed function: exp6
        function exp6ValueChanged(app, event)
            app.exper{6} = app.exp6.Value;
        end

        % Value changed function: exp7
        function exp7ValueChanged(app, event)
            app.exper{7} = app.exp7.Value;
        end

        % Button pushed function: Button3
        function exportParameters(app, event)
            varOut.Em = app.Em;
            varOut.R0 = app.R0;
            varOut.Ea = app.Ea;
            save('equivCircParam','varOut')
        end

        % Callback function
        function plotCurve(app, event)
            plot(app.UIAxes,flipud(app.SOC),app.V)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Button4 = uibutton(app.UIFigure, 'push');
            app.Button4.ButtonPushedFcn = createCallbackFcn(app, @calculateParam, true);
            app.Button4.Text = 'calculate parameters';
            app.Label5 = uilabel(app.UIFigure);
            app.Label5.Text = 'T (ºC)';
            app.T1 = uieditfield(app.UIFigure, 'numeric');
            app.T1.ValueChangedFcn = createCallbackFcn(app, @T_cbk_1, true);
            app.I_field_1 = uieditfield(app.UIFigure, 'numeric');
            app.I_field_1.ValueChangedFcn = createCallbackFcn(app, @I_cbk_1, true);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'current (A)';
            app.Button3 = uibutton(app.UIFigure, 'push');
            app.Button3.ButtonPushedFcn = createCallbackFcn(app, @exportParameters, true);
            app.Button3.Text = 'export parameters';
            app.exp1 = uieditfield(app.UIFigure, 'text');
            app.exp1.ValueChangedFcn = createCallbackFcn(app, @exp1ValueChanged, true);
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'exp 1 (Nx2)';
            app.filename = uieditfield(app.UIFigure, 'text');
            app.LabelEditField = uilabel(app.UIFigure);
            app.LabelEditField.Text = 'mat file path';
            app.Slider2 = uislider(app.UIFigure);
            app.Slider2.Limits = [-10 60];
            app.Slider2.ValueChangingFcn = createCallbackFcn(app, @SliderT, true);
            app.Slider2.Value = 25;
            app.Slider = uislider(app.UIFigure);
            app.Slider.Limits = [0 25];
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderI, true);
            app.Slider.Value = 1;
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Constant Current Discharge')
            xlabel(app.UIAxes, '1-SOC')
            ylabel(app.UIAxes, 'V (V)')
            app.UIAxes.XLim = [0 1];
            app.Label9 = uilabel(app.UIFigure);
            app.Label9.Text = 'T (ºC)';
            app.T2 = uieditfield(app.UIFigure, 'numeric');
            app.T2.ValueChangedFcn = createCallbackFcn(app, @T_cbk_2, true);
            app.exp2 = uieditfield(app.UIFigure, 'text');
            app.exp2.ValueChangedFcn = createCallbackFcn(app, @exp2ValueChanged, true);
            app.Label11 = uilabel(app.UIFigure);
            app.Label11.Text = 'exp 2 (Nx2)';
            app.Label12 = uilabel(app.UIFigure);
            app.Label12.Text = 'T (ºC)';
            app.T3 = uieditfield(app.UIFigure, 'numeric');
            app.T3.ValueChangedFcn = createCallbackFcn(app, @T_cbk_3, true);
            app.exp3 = uieditfield(app.UIFigure, 'text');
            app.exp3.ValueChangedFcn = createCallbackFcn(app, @exp3ValueChanged, true);
            app.Label14 = uilabel(app.UIFigure);
            app.Label14.Text = 'exp 3 (Nx2)';
            app.Label15 = uilabel(app.UIFigure);
            app.Label15.Text = 'T (ºC)';
            app.T4 = uieditfield(app.UIFigure, 'numeric');
            app.T4.ValueChangedFcn = createCallbackFcn(app, @T_cbk_4, true);
            app.Label16 = uilabel(app.UIFigure);
            app.Label16.Text = 'current (A)';
            app.I_field_4 = uieditfield(app.UIFigure, 'numeric');
            app.I_field_4.ValueChangedFcn = createCallbackFcn(app, @I_cbk_4, true);
            app.exp4 = uieditfield(app.UIFigure, 'text');
            app.exp4.ValueChangedFcn = createCallbackFcn(app, @exp4ValueChanged, true);
            app.Label17 = uilabel(app.UIFigure);
            app.Label17.Text = 'exp 4 (Nx2)';
            app.Label18 = uilabel(app.UIFigure);
            app.Label18.Text = 'current (A)';
            app.I_field_2 = uieditfield(app.UIFigure, 'numeric');
            app.I_field_2.ValueChangedFcn = createCallbackFcn(app, @I_cbk_2, true);
            app.Label19 = uilabel(app.UIFigure);
            app.Label19.Text = 'current (A)';
            app.I_field_3 = uieditfield(app.UIFigure, 'numeric');
            app.I_field_3.ValueChangedFcn = createCallbackFcn(app, @I_cbk_3, true);
            app.enterDataBtn = uibutton(app.UIFigure, 'push');
            app.enterDataBtn.ButtonPushedFcn = createCallbackFcn(app, @enterDataBtnCbk, true);
            app.enterDataBtn.Text = 'enter data';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Internal Resistance')
            xlabel(app.UIAxes2, '1-SOC')
            ylabel(app.UIAxes2, 'R (\Omega)')
            app.Label20 = uilabel(app.UIFigure);
            app.Label20.Text = 'I (A)';
            app.Label21 = uilabel(app.UIFigure);
            app.Label21.Text = 'T (ºC)';
            app.Label25 = uilabel(app.UIFigure);
            app.Label25.Text = 'T (ºC)';
            app.T7 = uieditfield(app.UIFigure, 'numeric');
            app.T7.ValueChangedFcn = createCallbackFcn(app, @T_cbk_7, true);
            app.Label26 = uilabel(app.UIFigure);
            app.Label26.Text = 'T (ºC)';
            app.T6 = uieditfield(app.UIFigure, 'numeric');
            app.T6.ValueChangedFcn = createCallbackFcn(app, @T_cbk_6, true);
            app.Label27 = uilabel(app.UIFigure);
            app.Label27.Text = 'current (A)';
            app.I_field_7 = uieditfield(app.UIFigure, 'numeric');
            app.I_field_7.ValueChangedFcn = createCallbackFcn(app, @I_cbk_7, true);
            app.Label28 = uilabel(app.UIFigure);
            app.Label28.Text = 'current (A)';
            app.I_field_6 = uieditfield(app.UIFigure, 'numeric');
            app.I_field_6.ValueChangedFcn = createCallbackFcn(app, @I_cbk_6, true);
            app.exp7 = uieditfield(app.UIFigure, 'text');
            app.exp7.ValueChangedFcn = createCallbackFcn(app, @exp7ValueChanged, true);
            app.Label29 = uilabel(app.UIFigure);
            app.Label29.Text = 'exp 7 (Nx2)';
            app.exp6 = uieditfield(app.UIFigure, 'text');
            app.exp6.ValueChangedFcn = createCallbackFcn(app, @exp6ValueChanged, true);
            app.Label30 = uilabel(app.UIFigure);
            app.Label30.Text = 'exp 6 (Nx2)';
            app.Label31 = uilabel(app.UIFigure);
            app.Label31.Text = 'T (ºC)';
            app.T5 = uieditfield(app.UIFigure, 'numeric');
            app.T5.ValueChangedFcn = createCallbackFcn(app, @T_cbk_5, true);
            app.Label32 = uilabel(app.UIFigure);
            app.Label32.Text = 'current (A)';
            app.I_field_5 = uieditfield(app.UIFigure, 'numeric');
            app.I_field_5.ValueChangedFcn = createCallbackFcn(app, @I_cbk_5, true);
            app.exp5 = uieditfield(app.UIFigure, 'text');
            app.exp5.ValueChangedFcn = createCallbackFcn(app, @exp5ValueChanged, true);
            app.Label33 = uilabel(app.UIFigure);
            app.Label33.Text = 'exp 5 (Nx2)';
            app.Label34 = uilabel(app.UIFigure);
            app.Label34.Text = 'Resistance Estimation';
            app.Label35 = uilabel(app.UIFigure);
            app.Label35.Text = 'Validation';
            app.Label36 = uilabel(app.UIFigure);
            app.Label36.Text = 'Activation Energy Estimation';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = batteryAppValidation

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end